classdef demonstratorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Howtoworkit1TypeintheLabel     matlab.ui.control.Label
        AveragespeedmsEditField        matlab.ui.control.NumericEditField
        AveragespeedmsEditFieldLabel   matlab.ui.control.Label
        MaximumspeedmsEditField        matlab.ui.control.NumericEditField
        MaximumspeedmsEditFieldLabel   matlab.ui.control.Label
        SavedataCheckBox               matlab.ui.control.CheckBox
        StartloggingButton             matlab.ui.control.Button
        EEE4022SFinalYearProjectLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        StudentFlourishOluwamakindeSupervisorDrYunusAbdulGaffarLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Panel2                         matlab.ui.container.Panel
        SamplingfrequencyEditField     matlab.ui.control.EditField
        SamplingfrequencyEditFieldLabel  matlab.ui.control.Label
        FrameoverlapEditField          matlab.ui.control.NumericEditField
        FrameoverlapEditFieldLabel     matlab.ui.control.Label
        SamplingtimesEditField         matlab.ui.control.NumericEditField
        SamplingtimesEditFieldLabel    matlab.ui.control.Label
        TransmitfrequencyHzEditField   matlab.ui.control.NumericEditField
        TransmitfrequencyHzEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear all;
            close all;
            clc;
            closereq
        end

        % Button pushed function: StartloggingButton
        function StartloggingButtonPushed(app, event)
            clc;
            % Start logging signals returned and process this data to
            % display velocities and spectrogram

            % Read input values
            % threshold = app.ThresholdEdit.Value;
            %numSamplesPerFrame = str2double(app.SamplesperframeEditField.Value);
            %NumSamplesPerFrame = 2^(numSamplesPerFrame);
            overlapFactor = app.FrameoverlapEditField.Value;
            Fc_Hz = app.TransmitfrequencyHzEditField.Value;
            TimeDuration_s = app.SamplingtimesEditField.Value;
            Fs = str2double(app.SamplingfrequencyEditField.Value);
    
            %--------------------------------------------------------------
            dt = 1/Fs;                           % Sampling period
            t = 0:dt:(TimeDuration_s);           % time vector for pulse
            v_factor = 343/(2*Fc_Hz);            % Velocity conversion factor. f --> v
            TxSignal = sin(2*pi*Fc_Hz*t);        % Pure sinusoid to be transmitted  
            
            soundsc(TxSignal, Fs, 24); % Transmit the signal. 24 is resolution in bits

            % Tell user that logging has started
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Logging started','Message','Transmitting and receiving...');
            d.Value = 0.33;
            
            % Setup recording and sound card
            RecLength_samples = length(TxSignal);
            RecLength_s = RecLength_samples*1/Fs; 
            recObj = audiorecorder(Fs,24,1);
            recordblocking(recObj, RecLength_s);
            RX_signal = getaudiodata(recObj);
            %data(1:100)=0; % Cut beginning and end of data for startup time.
            %data(end-100:end)=0;
            
            % Save data checkbox
            if app.SavedataCheckBox.Value == 1
                [file, path] = uiputfile('*.mat', 'Please enter a filename');
                try
                    save(fullfile(path, file), 'Fc_Hz', 'Fs', 'TimeDuration_s', 'RX_signal', 'TxSignal');
                catch e
                    disp("No file selected.");
                end
            end

            % Call processSignal function 
            [data_out] = processSignal(Fs, Fc_Hz, TimeDuration_s, TxSignal, RX_signal);

            d.Message = 'Processing data...';
            d.Value = 0.66;
            
            % Signal processing is now complete; now compute velocities and spectrogram
            % Call customSTFT function
            [S, f, t_s] = customSTFT(data_out, Fs, 4096, overlapFactor);
            
            datan = abs(S);
            datan = datan-min(datan(:));
            datan = datan/max(datan(:));
            datan = 20*log10(datan);
            
            for i = 2:length(f)-1
                for j = 2:length(t_s)-1
                   if datan(i,j) < -60
                       datan(i,j) = -100;
                   end
                end
            end
            
            datan = smoothdata(datan, 'movmedian', 50);
            
            % Calculate speeds (max, avg, instantaneous)
            max_frequencies = zeros(length(t_s),1);
            for frame = 1:length(t_s)
                [max_S, index] = max(datan(:, frame)); %gets max FFT magnitude in frame
                if (max_S > -60)
                    max_frequencies(frame) = f(index); %store corresponding frequency value
                else
                    max_frequencies(frame) = 0;
                end
            end
            
            k = (343/Fc_Hz)/2;
            convert = 3.6; % convert from m/s to km/h
            max_speed = max(abs(max_frequencies))*k*convert; %maximum speed
            avg_speed = mean(abs(max_frequencies))*k*convert; %average speed
            instant_speed = max_frequencies*k*convert;
            
            instant_speed = smoothdata(instant_speed, 'movmean', 5);

            % Display speed values on the UI
            app.AveragespeedmsEditField.Value = avg_speed;
            app.MaximumspeedmsEditField.Value = max_speed;

            % Plot the speeds
            plot(app.UIAxes, t_s, instant_speed);
            yline(app.UIAxes, max_speed, "r");
            yline(app.UIAxes, avg_speed, "b--");
            title("Maximum, average, and instantaneous speeds");
            legend(app.UIAxes, 'Instantaneous speed', 'Maximum speed', 'Average speed')
            
            % Plot the spectrogram 
            v = f.*k;
            figure; imagesc(t_s, v, datan, [-60, 0]);
            ylim([0 10])
            title("Spectrogram of received signal")
            xlabel('Time (s)');
            ylabel('Doppler Frequency (kHz)');
            grid on;
            colorbar;
            colormap('jet');
            set(gca,'YDir','normal')
           
            d.Message = 'Complete!';
            d.Value = 1;
            pause(1);
            close(d);
        end

        % Callback function
        function SavedataButtonPushed(app, event)
            % Saves data to a file of choice if button is pushed
            if app.SavedataButton.Value == 1
                [file, path] = uiputfile('*.mat', 'Please enter a filename');
                try
                    save(fullfile(path, file), 'data', 'Fc_Hz', 'Fs', 'TimeDuration_s');
                catch e
                    disp("No file selected.");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Instantaneous, Average, and Maximum Speed of Moving Vehicle')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Speed (km/h)')
            zlabel(app.UIAxes, 'Z')
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Radar parameters';
            app.TransmitfrequencyHzEditFieldLabel = uilabel(app.Panel2);
            app.TransmitfrequencyHzEditFieldLabel.Text = 'Transmit frequency (Hz)';
            app.TransmitfrequencyHzEditField = uieditfield(app.Panel2, 'numeric');
            app.TransmitfrequencyHzEditField.Limits = [0 Inf];
            app.TransmitfrequencyHzEditField.Value = 40000;
            app.SamplingtimesEditFieldLabel = uilabel(app.Panel2);
            app.SamplingtimesEditFieldLabel.Text = 'Sampling time (s)';
            app.SamplingtimesEditField = uieditfield(app.Panel2, 'numeric');
            app.SamplingtimesEditField.Limits = [0 500];
            app.FrameoverlapEditFieldLabel = uilabel(app.Panel2);
            app.FrameoverlapEditFieldLabel.Text = 'Frame overlap (%)';
            app.FrameoverlapEditField = uieditfield(app.Panel2, 'numeric');
            app.FrameoverlapEditField.Limits = [0 1];
            app.FrameoverlapEditField.Value = 0.9;
            app.SamplingfrequencyEditFieldLabel = uilabel(app.Panel2);
            app.SamplingfrequencyEditFieldLabel.Text = 'Sampling frequency';
            app.SamplingfrequencyEditField = uieditfield(app.Panel2, 'text');
            app.SamplingfrequencyEditField.Value = '192000';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UCT_Logo.jpg');
            app.StudentFlourishOluwamakindeSupervisorDrYunusAbdulGaffarLabel = uilabel(app.Panel);
            app.StudentFlourishOluwamakindeSupervisorDrYunusAbdulGaffarLabel.Text = {'Student: Flourish Oluwamakinde'; 'Supervisor: Dr Yunus Abdul Gaffar'};
            app.EEE4022SFinalYearProjectLabel = uilabel(app.UIFigure);
            app.EEE4022SFinalYearProjectLabel.Text = {'EEE4022S Final Year Project 2022'; 'PC-Based Ultrasonic Radar Demonstrator for Measuring the Speed of Vehicles in Parking Areas'};
            app.StartloggingButton = uibutton(app.UIFigure, 'push');
            app.StartloggingButton.ButtonPushedFcn = createCallbackFcn(app, @StartloggingButtonPushed, true);
            app.StartloggingButton.Text = 'Start logging';
            app.SavedataCheckBox = uicheckbox(app.UIFigure);
            app.SavedataCheckBox.Text = 'Save data';
            app.MaximumspeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumspeedmsEditFieldLabel.Text = 'Maximum speed (m/s)';
            app.MaximumspeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragespeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.AveragespeedmsEditFieldLabel.Text = 'Average speed (m/s)';
            app.AveragespeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Howtoworkit1TypeintheLabel = uilabel(app.UIFigure);
            app.Howtoworkit1TypeintheLabel.Text = {'1. Type in the parameters of your transmitting signal. '; '2. The sampling frequency should be at least twice '; 'the value of the transmit frequency.'; '3. ''Start logging'' will begin transmitting, receiving, and'; ' processing the data.'; '3. Click the ''Save data'' check box if you want to save'; 'the data when you start logging.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demonstratorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end